classdef MultiCLEM_analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MultiCLEMAnalyzerUIFigure       matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PipelineTab                     matlab.ui.container.Tab
        EXITButton_2                    matlab.ui.control.Button
        StatusTextAreaLabel             matlab.ui.control.Label
        StatusTextArea                  matlab.ui.control.TextArea
        DataPreparationPanel            matlab.ui.container.Panel
        blendonWindowsButton            matlab.ui.control.Button
        autoblendbox                    matlab.ui.control.CheckBox
        cutforilastikbox                matlab.ui.control.CheckBox
        trainilastikbox                 matlab.ui.control.CheckBox
        runilastikbox                   matlab.ui.control.CheckBox
        findcellsbox                    matlab.ui.control.CheckBox
        correlatebox                    matlab.ui.control.CheckBox
        RunonlycurrentsquareCheckBox    matlab.ui.control.CheckBox
        InteractivecutCheckBox          matlab.ui.control.CheckBox
        UsefirstilpforallCheckBox       matlab.ui.control.CheckBox
        NumberofsquaresEditFieldLabel   matlab.ui.control.Label
        NumberofsquaresEditField        matlab.ui.control.NumericEditField
        o1_autoblendrun                 matlab.ui.control.Button
        BlendmontageLampLabel           matlab.ui.control.Label
        BlendmontageLamp                matlab.ui.control.Lamp
        o2_cutforilastikrun             matlab.ui.control.Button
        MakepiecesfortrainingilastikLampLabel  matlab.ui.control.Label
        MakepiecesfortrainingilastikLamp  matlab.ui.control.Lamp
        o3_trainilastikrun              matlab.ui.control.Button
        TrainilastikLampLabel           matlab.ui.control.Label
        TrainilastikLamp                matlab.ui.control.Lamp
        o4_runilastikrun                matlab.ui.control.Button
        RunilastikLabel                 matlab.ui.control.Label
        RunilastikLamp                  matlab.ui.control.Lamp
        o5_findcellsrun                 matlab.ui.control.Button
        FindcellsandcellwallsLampLabel  matlab.ui.control.Label
        FindcellsandcellwallsLamp       matlab.ui.control.Lamp
        o6_correlaterun                 matlab.ui.control.Button
        CorrelateEMtoLMLabel            matlab.ui.control.Label
        CorrelateEMtoLMLamp             matlab.ui.control.Lamp
        autorunButton                   matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        BarcodeReadingPanel             matlab.ui.container.Panel
        SquarestoanalyzeEditFieldLabel  matlab.ui.control.Label
        SquarestoanalyzeEditField       matlab.ui.control.EditField
        OutputIDtablenameLabel          matlab.ui.control.Label
        OutputIDtablenameEditField      matlab.ui.control.EditField
        o7_ClassifyCells                matlab.ui.control.Button
        ClassifycellsLabel              matlab.ui.control.Label
        ClassifycellsLamp               matlab.ui.control.Lamp
        HelpSqAnalyse                   matlab.ui.control.Button
        HelpIDtableName                 matlab.ui.control.Button
        ImporttoNavigatorPanel          matlab.ui.container.Panel
        o10_writenavfilerun             matlab.ui.control.Button
        HelpSqtoNav                     matlab.ui.control.Button
        HelpNavIn                       matlab.ui.control.Button
        SquarestoaddEditFieldLabel      matlab.ui.control.Label
        SquarestoaddEditField           matlab.ui.control.EditField
        InputNavigatornameEditFieldLabel  matlab.ui.control.Label
        InputNavigatornameEditField     matlab.ui.control.EditField
        OutputNavigatornameEditFieldLabel  matlab.ui.control.Label
        OutputNavigatornameEditField    matlab.ui.control.EditField
        DBTableEditFieldLabel           matlab.ui.control.Label
        DBTableEditField_nav            matlab.ui.control.EditField
        browseButton_7                  matlab.ui.control.Button
        EvaluationandQualitycontrolPanel  matlab.ui.container.Panel
        BrowseSelectrun                 matlab.ui.control.Button
        AssignbarcodesRun               matlab.ui.control.Button
        runfinalcrop                    matlab.ui.control.Button
        RunCropCells                    matlab.ui.control.Button
        browseButton_6                  matlab.ui.control.Button
        DBtableLabel                    matlab.ui.control.Label
        DBtableEditField                matlab.ui.control.EditField
        IncludedCheckBox_2              matlab.ui.control.CheckBox
        ApprovedCheckBox_2              matlab.ui.control.CheckBox
        HelpDBtable_2                   matlab.ui.control.Button
        ExportonlyLabel                 matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        EXITButton                      matlab.ui.control.Button
        debugcorrelationCheckBox        matlab.ui.control.CheckBox
        InputdataPanel                  matlab.ui.container.Panel
        GriddirEditFieldLabel           matlab.ui.control.Label
        GriddirEditField                matlab.ui.control.EditField
        ilastiksetupfolderEditFieldLabel  matlab.ui.control.Label
        ilastiksetupfolderEditField     matlab.ui.control.EditField
        browseButton_3                  matlab.ui.control.Button
        browseButton_4                  matlab.ui.control.Button
        OrganizeLMCheckBox              matlab.ui.control.CheckBox
        LMfilerootEditFieldLabel        matlab.ui.control.Label
        LMfilerootEditField             matlab.ui.control.EditField
        mrcfilerootLabel                matlab.ui.control.Label
        mrcfilerootEditField            matlab.ui.control.EditField
        ButtonHelpMrcRoot               matlab.ui.control.Button
        ReduceEMEditFieldLabel          matlab.ui.control.Label
        ReduceEMEditField               matlab.ui.control.NumericEditField
        ButtonHelpReduceEM              matlab.ui.control.Button
        CutforilastikPanel              matlab.ui.container.Panel
        TopLeftXEditFieldLabel          matlab.ui.control.Label
        TopLeftXEditField               matlab.ui.control.NumericEditField
        TopLeftYEditFieldLabel          matlab.ui.control.Label
        TopLeftYEditField               matlab.ui.control.NumericEditField
        SizeEditFieldLabel              matlab.ui.control.Label
        SizeEditField                   matlab.ui.control.NumericEditField
        ButtonHelpCut                   matlab.ui.control.Button
        FindcellsinEMPanel              matlab.ui.container.Panel
        Holesizem2EditFieldLabel        matlab.ui.control.Label
        Holesizem2EditField             matlab.ui.control.NumericEditField
        MinAream2EditFieldLabel         matlab.ui.control.Label
        MinAream2EditField              matlab.ui.control.NumericEditField
        MincicularityEditFieldLabel     matlab.ui.control.Label
        MincicularityEditField          matlab.ui.control.NumericEditField
        HforwatershedEditFieldLabel     matlab.ui.control.Label
        HforwatershedEditField          matlab.ui.control.NumericEditField
        ButtonHoleSize                  matlab.ui.control.Button
        ButtonHelpMinArea               matlab.ui.control.Button
        ButtonHelpMinCirc               matlab.ui.control.Button
        ButtonHelpHforWatershed         matlab.ui.control.Button
        SmoothingRnmEditFieldLabel      matlab.ui.control.Label
        SmoothingRnmEditField           matlab.ui.control.NumericEditField
        DilationRnmEditFieldLabel       matlab.ui.control.Label
        DilationRnmEditField            matlab.ui.control.NumericEditField
        ButtonHelpSmoothing             matlab.ui.control.Button
        ShowallintermediateimagesCheckBox  matlab.ui.control.CheckBox
        ButtonHelpDilationR             matlab.ui.control.Button
        ButtonHelpDilationR_2           matlab.ui.control.Button
        IlastiklabelsPanel              matlab.ui.container.Panel
        CellsEditFieldLabel             matlab.ui.control.Label
        CellsEditField                  matlab.ui.control.NumericEditField
        HolesEditFieldLabel             matlab.ui.control.Label
        HolesEditField                  matlab.ui.control.NumericEditField
        ResinEditFieldLabel             matlab.ui.control.Label
        ResinEditField                  matlab.ui.control.NumericEditField
        BlackEditFieldLabel             matlab.ui.control.Label
        BlackEditField                  matlab.ui.control.NumericEditField
        EMtoLMcorrelationPanel          matlab.ui.container.Panel
        TransformDropDownLabel          matlab.ui.control.Label
        TransformDropDown               matlab.ui.control.DropDown
        NormalizeLMCheckBox             matlab.ui.control.CheckBox
        AlsodoEMLMCheckBox              matlab.ui.control.CheckBox
        FlipLMCheckBox                  matlab.ui.control.CheckBox
        ButtonHelpCorrTransform         matlab.ui.control.Button
        ButtonHelpCorrNormLM            matlab.ui.control.Button
        ButtonHelpCorrDoEm2LM           matlab.ui.control.Button
        ButtonHelpCorrFlipLM            matlab.ui.control.Button
        MeasurefluorescentsignalandclassifycellsPanel  matlab.ui.container.Panel
        SubtractbackgroundCheckBox      matlab.ui.control.CheckBox
        ShowimagesCheckBox              matlab.ui.control.CheckBox
        RumEditFieldLabel               matlab.ui.control.Label
        RumEditField                    matlab.ui.control.NumericEditField
        FluorescentchannelsLabel        matlab.ui.control.Label
        touseandtheirorderEditFieldLabel  matlab.ui.control.Label
        touseandtheirorderEditField     matlab.ui.control.EditField
        HelpSubtractBG                  matlab.ui.control.Button
        HelpFlChannelsToUse             matlab.ui.control.Button
        HelpNumClusters                 matlab.ui.control.Button
        ofclustersEditFieldLabel        matlab.ui.control.Label
        ofclustersEditField             matlab.ui.control.NumericEditField
        HelpFlChannelsToUse_2           matlab.ui.control.Button
        NavigatoritemsimportPanel       matlab.ui.container.Panel
        TransformDropDown_2Label        matlab.ui.control.Label
        XYtoStageTransformDropDown      matlab.ui.control.DropDown
        ButtonHelpXYStageTform          matlab.ui.control.Button
        CCscaledownEditFieldLabel       matlab.ui.control.Label
        CCscaledownEditField            matlab.ui.control.NumericEditField
        ButtonHelpCCscaledown           matlab.ui.control.Button
        AddAcquireCheckBox              matlab.ui.control.CheckBox
        ImportmodeDropDownLabel         matlab.ui.control.Label
        ImportmodeDropDown              matlab.ui.control.DropDown
        HelpImportMode                  matlab.ui.control.Button
        HelpAddAcquire                  matlab.ui.control.Button
        IncludedCheckBox                matlab.ui.control.CheckBox
        ApprovedCheckBox                matlab.ui.control.CheckBox
        HelpDBtable                     matlab.ui.control.Button
        HelpTextAreaLabel               matlab.ui.control.Label
        HelpTextArea                    matlab.ui.control.TextArea
        BrowsingPanel                   matlab.ui.container.Panel
        PanelsizeEditFieldLabel         matlab.ui.control.Label
        PanelsizeEditField              matlab.ui.control.NumericEditField
        NrowsEditFieldLabel             matlab.ui.control.Label
        NrowsEditField                  matlab.ui.control.NumericEditField
        HelpPanelSize                   matlab.ui.control.Button
        HelpNrows                       matlab.ui.control.Button
        autoloadCheckBox                matlab.ui.control.CheckBox
        savesetingsButton               matlab.ui.control.Button
        loadsetings                     matlab.ui.control.Button
        ExportcellsPanel                matlab.ui.container.Panel
        CropsizemEditFieldLabel         matlab.ui.control.Label
        CropsizemEditField              matlab.ui.control.NumericEditField
        HelpCropSize                    matlab.ui.control.Button
        ResizingEditFieldLabel          matlab.ui.control.Label
        ResizingEditField               matlab.ui.control.NumericEditField
        AddLMchannelsEditFieldLabel     matlab.ui.control.Label
        AddLMchannelsEditField          matlab.ui.control.EditField
        HelpResizing                    matlab.ui.control.Button
    end


    properties (Access = public)
        mrc % Description
        lcolor % Description

        scale % Description
        heppy % Description
    end

    properties (Access = private)
        root % Description
        workdir % Description
        ilastikfolder % Description
        ilasticsetup % Description
        EMscale % Description
        holesize % Description
        r1 % Description
        r2 % Description
        wsh % Description
        areami % Description
        circ % Description
        speedscale % Description
        transf1 % Description
        transf2 % Description
        norm % Description
        innev % Description
        outnev % Description
        stagen % Description
        acq % Description
        var % Description
        lb % Description
        mb % Description
        mbo % Description
        hb % Description
        sq_num % Description
    end

    methods (Access = private)
    
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %check if its the first run
            %             set(app.MultiCLEMAnalyzerUIFigure,'Visible','off')
            %             first_run=[userpath filesep 'firstrun.mat'];
            %             if exist(first_run)==0
            %                 %startup function
            %                 if ispc
            %                     OS = 'win';
            %                 elseif isunix
            %                     OS = 'linux';
            %                     %mac
            %                 end
            %                 
            %                 waitfor(msgbox('Make sure that Ilastik is installed!', 'Error','error'));
            %                 web('http://ilastik.org/download.html', '-browser')
            %                 if OS =='win'
            %                     waitfor(msgbox('cygwin could not be fund!', 'Error','error'));
            %                     web('https://cygwin.com/install.html', '-browser')
            %                 end
            %                 
            %                 waitfor(msgbox('Make sure that imod is installed!', 'Error','error'));
            %                 if OS =='win'
            %                     web('http://bio3d.colorado.edu/imod/download.html#Latest-Windows', '-browser')
            %                 elseif OS =='linux'
            %                     web('http://bio3d.colorado.edu/imod/download.html#Latest-Linux', '-browser')
            %                 end
            %                 fr=1;
            %                 save(first_run,'fr')
            %             else
            %             end
            
            % allows the loading of saved setting automaticly  if auto load is checked, setting file is saved as htemseting.mat in userpath folder.
            %the data regurding the auto save option is also stord in user path as htemstartup.mat
            startup_status=[userpath filesep 'htemstartup.mat'];
            if exist(startup_status)==2
            load(startup_status)
            if htemstartup_start ==1
            %loading the saved setting as tamp verabole and load only the set setting 
            defoltpath = [userpath,filesep,'htemseting.mat'];
            temp = load(defoltpath,'app');
            app.GriddirEditField.Value = temp.app.GriddirEditField.Value;
            app.ilastiksetupfolderEditField.Value = temp.app.ilastiksetupfolderEditField.Value;
            app.LMfilerootEditField.Value = temp.app.LMfilerootEditField.Value;
            app.mrcfilerootEditField.Value = temp.app.mrcfilerootEditField.Value;
            app.ReduceEMEditField.Value = temp.app.ReduceEMEditField.Value;
            app.TopLeftXEditField.Value = temp.app.TopLeftXEditField.Value;
            app.TopLeftYEditField.Value = temp.app.TopLeftYEditField.Value;
            app.SizeEditField.Value = temp.app.SizeEditField.Value;
            app.Holesizem2EditField.Value = temp.app.Holesizem2EditField.Value;
            app.MinAream2EditField.Value = temp.app.MinAream2EditField.Value;
            app.MincicularityEditField.Value = temp.app.MincicularityEditField.Value;
            app.HforwatershedEditField.Value = temp.app.HforwatershedEditField.Value;
            app.SmoothingRnmEditField.Value = temp.app.SmoothingRnmEditField.Value;
            app.DilationRnmEditField.Value = temp.app.DilationRnmEditField.Value;
            app.CellsEditField.Value = temp.app.CellsEditField.Value;
            app.HolesEditField.Value = temp.app.HolesEditField.Value;
            app.ResinEditField.Value = temp.app.ResinEditField.Value;
            app.BlackEditField.Value = temp.app.BlackEditField.Value;
            app.RumEditField.Value = temp.app.RumEditField.Value;
            app.touseandtheirorderEditField.Value = temp.app.touseandtheirorderEditField.Value;
            app.ofclustersEditField.Value = temp.app.ofclustersEditField.Value;
            app.CropsizemEditField.Value = temp.app.CropsizemEditField.Value;
            app.ResizingEditField.Value = temp.app.ResizingEditField.Value;
            app.PanelsizeEditField.Value = temp.app.PanelsizeEditField.Value;
            app.NrowsEditField.Value = temp.app.NrowsEditField.Value;
            drawnow  
            app.autoloadCheckBox.Value=1;
            else
            end
            else 
            end
            
            set(app.MultiCLEMAnalyzerUIFigure,'Visible','on') 
            curr_status = '';
            app.HelpTextArea.Value = 'Welcome to the High-Throughput Yeast CLEM Processing. Press little [?] buttons to get help for different parameters';
        end

        % Button pushed function: ButtonHelpCCscaledown
        function ButtonHelpCCscaledownPushed(app, event)
            app.HelpTextArea.Value = 'Serves the same purpose as REDUCE EM parameter: to speed up computation. This one rescales EM images for cross-correaltion, and can (and should) be around 0.1. Otherwise takes a while for montages with many tiles.';
        end

        % Button pushed function: ButtonHelpCorrDoEm2LM
        function ButtonHelpCorrDoEm2LMPushed(app, event)
            app.HelpTextArea.Value = 'Additionally transform EM image and cell wall masks (if they are available). Masks take some additional time';
        end

        % Button pushed function: ButtonHelpCorrFlipLM
        function ButtonHelpCorrFlipLMPushed(app, event)
            app.HelpTextArea.Value = 'Mirror the LM image. Transformation can not mirror images, it should be rotation, translation, and scaling. If you insert your sample in the EM the wrong way, LM should be mirrored.';
        end

        % Button pushed function: ButtonHelpCorrNormLM
        function ButtonHelpCorrNormLMPushed(app, event)
            app.HelpTextArea.Value = 'If LM data is 16-bit but actually something like 11 or 12 bit (0-4095) runs a special normalization script so that cells can be clearly seen for corralation. Saves normalized images as _norm_ch_n.tif. These are not for measuring intensities.';
        end

        % Button pushed function: ButtonHelpCorrTransform
        function ButtonHelpCorrTransformPushed(app, event)
            app.HelpTextArea.Value = 'If montage is done well, affine transform with ~8 registration point pairs is enough. Can be tested with other transforms as well.';
        end

        % Button pushed function: ButtonHelpCut
        function ButtonHelpCutPushed(app, event)
            app.HelpTextArea.Value = 'Position and size of 4 images cut out from the montage to train ilastik software. Top left X and Y are pixel coordinates or the top left corner of the top left rectangle (in original pixels) and Size is side length'; 
        end

        % Button pushed function: ButtonHelpHforWatershed
        function ButtonHelpHforWatershedPushed(app, event)
            app.HelpTextArea.Value = 'Change this parameter only if watershed segmentation goes wrong - not enough segmentation, or oversegmetation with borders crossing through cells. Read image processing toolbox tips for details. Actually, for not enough case there is no space left to reduce it, so try to change smoothening parameters in the code.';
        end

        % Button pushed function: ButtonHelpMinArea
        function ButtonHelpMinAreaPushed(app, event)
            app.HelpTextArea.Value = 'Minimal area to count blob as a cell (in sq.microns). I found that small buds are usually above 1 sq.micron. This and previous property easy to estimate in ImageJ';
        end

        % Button pushed function: ButtonHelpMinCirc
        function ButtonHelpMinCircPushed(app, event)
            app.HelpTextArea.Value = 'Minimal circularity to count blob as a cell. Circularity is defined like in ImageJ (see its help). 0 - is like a line or so, 1 - perfect circle. Cells are around 0.8';
        end

        % Button pushed function: ButtonHelpMrcRoot
        function ButtonHelpMrcRootPushed(app, event)
            app.HelpTextArea.Value = 'The first letters of micrograph file name, before the number. Micrographs should be named the same way, but differ in number before extension: mm1.mrc, mm2.mrc ... The same principle for light micrographs.';
        end

        % Callback function
        function ButtonHelpPixelsizePushed(app, event)
            app.HelpTextArea.Value = 'Very important to have correct pixel size, because parameters stated in microns depend on it. To find it out run header my_image.mrc or look in the .mdoc file (they usually have the value in angstroms). Ask if your EM is calibrated';
        end

        % Button pushed function: ButtonHelpReduceEM
        function ButtonHelpReduceEMPushed(app, event)
            app.HelpTextArea.Value = 'Factor to reduce the size of EM montage (and its segmentations) to speed up calculations. Typical value around 0.2. Higher value takes much longer time, lower might reduce precision. This parameter is used throughout the whole pipeline (except finding tiles, it has its own additional factor to reduce images for CC), so all coordinates saved anywhere are coordinates in reduced images, and all EM (or LM transformed to EM) has this reduced size.';
        end

        % Button pushed function: ButtonHelpSmoothing
        function ButtonHelpSmoothingPushed(app, event)
            app.HelpTextArea.Value = 'In nanometers. Regulates smoothing of the cell outlines. This is size of the structuring element used to perform morphological opening of the image.';
        end

        % Button pushed function: ButtonHelpXYStageTform
        function ButtonHelpXYStageTformPushed(app, event)
            app.HelpTextArea.Value = 'For ''Transform'' import mode. Transformation type between montage coordinates and EM stage coordinates. I found that affine and projective works best.';
        end

        % Button pushed function: ButtonHoleSize
        function ButtonHoleSizePushed(app, event)
            app.HelpTextArea.Value = 'Maximal area of holes inside the cells to fill in (typically lipid droplets) so that segmentation works well. Otherwise cell will be cut in halves. I measured <0.5 sq.microns. Too high number will result in filling in the gaps between neighbouring cells and wrong segmentation as well, so pay attention to this parameter. IMPORTANT: cells with morphologival abberations have large translucent vacuoles. This parameter should be increased to get these cells included. But watch for space between cells. If it gets filled, option to filter by curvature should be coded in.';
        end

        % Value changing function: Slider
        function EMscaleEditFieldValueChanged(app, event)
           app.EMscale.Value = app.ReduceEMEditField.Value;
           app.scale.Value = app.ReduceEMEditField.Value; 
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            choice = questdlg('are you sure?','Yes','no');
             switch choice
              case 'Yes'
                app.heppy = 1;
              case 'no'
                app.heppy = 0;
             end
             if app.heppy==1
            delete(app.MultiCLEMAnalyzerUIFigure)
             elseif app.heppy==0
             return
             else 
              return
             end
            
            
        end

        % Button pushed function: EXITButton_2
        function EXITButton_2Pushed(app, event)
    choice = questdlg('are you sure?','Yes','no');
             switch choice
              case 'Yes'
                app.heppy = 1;
              case 'no'
                app.heppy = 0;
             end
             if app.heppy==1
            delete(app.MultiCLEMAnalyzerUIFigure)
             elseif app.heppy==0
             return
             else 
              return
             end
            
        end

        % Value changed function: GriddirEditField
        function GriddirEditFieldValueChanged(app, event)
            
        end

        % Value changed function: LMfilerootEditField
        function LMfilerootEditFieldValueChanged(app, event)
            value = app.LMfilerootEditField.Value;
            
        end

        % Value changed function: NumberofsquaresEditField
        function NumberofsquaresEditFieldValueChanged(app, event)
            app.sq_num.value = app.NumberofsquaresEditField.Value;
            
        end

        % Callback function
        function OSEditFieldValueChanged(app, event)
            if ispc==1
               changingValue = 'windows';
            elseif isunix ==1
               changingValue = 'linux';
            end
              app.OSEditField.Value = changingValue;
        end

        % Callback function
        function OSEditFieldValueChanging(app, event)
           
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.EMscale.value = app.Slider.Value;
            
        end

        % Callback function
        function SpeedScaleEditFieldValueChanged(app, event)
            app.speedscale.value = app.CCscaledownEditField.Value;
            
        end

        % Callback function
        function acquireEditFieldValueChanged(app, event)
            app.acq.value = app.acquireEditField.Value;
            
        end

        % Callback function
        function areaminEditFieldValueChanged(app, event)
            app.areami.value = app.MinAream2EditField.Value;
            
        end

        % Value changed function: autoblendbox
        function autoblendboxValueChanged(app, event)
             
            
        end

        % Button pushed function: o1_autoblendrun
        function o1_autoblendrunButtonPushed(app, event)
            global curr_status;
            for i=1:app.NumberofsquaresEditField.Value 
                if app.RunonlycurrentsquareCheckBox.Value==1
                    i =app.NumberofsquaresEditField.Value;
                    
                    curr_status = [curr_status sprintf('Starting blend of square %d\n', i)];
                    app.StatusTextArea.Value = curr_status;
                    curr_status = [curr_status sprintf('%s\n', muclem_autoblend(i,app.GriddirEditField.Value,app.mrcfilerootEditField.Value,...
                        app.LMfilerootEditField.Value,app.OrganizeLMCheckBox.Value))];
                    app.StatusTextArea.Value = curr_status;
                return
                else
                   curr_status = [curr_status sprintf('Starting blend of square %d\n', i)];
                   app.StatusTextArea.Value = curr_status;
                   curr_status = [curr_status sprintf('%s\n', muclem_autoblend(i,app.GriddirEditField.Value,app.mrcfilerootEditField.Value,...
                        app.LMfilerootEditField.Value,app.OrganizeLMCheckBox.Value))];
                   app.StatusTextArea.Value = curr_status; 
                end
            end
            
            app.BlendmontageLamp.Color = [0.00,1.00,0.00];
            
        end

        % Button pushed function: autorunButton
        function autorunButtonPushed(app, event)
            
            %1.run folder and autoblend script
            if app.autoblendbox.Value == 1 
                o1_autoblendrunButtonPushed(app, event);    
            app.BlendmontageLamp.Color = [0.00,1.00,0.00];
            end
            
            %2.cut images for ilastik
            if app.cutforilastikbox.Value == 1 
                 o2_cutforilastikrunButtonPushed(app, event);
            app.MakepiecesfortrainingilastikLamp.Color = [0.00,1.00,0.00];
            end
            
            %3.run ilastik for segmentation
            if app.trainilastikbox.Value == 1 
                 o3_trainilastikrunButtonPushed(app, event);
            app.TrainilastikLamp.Color = [0.00,1.00,0.00];
            end
            
            %4.run ilastik headless
            if app.runilastikbox.Value == 1 
                o4_runilastikrunButtonPushed(app, event);
            app.RunilastikLamp.Color = [0.00,1.00,0.00];
            end
            
            %5. run myclem_mm_find_cells_on_stage_for_pipe
            if app.findcellsbox.Value == 1 
                o5_findcellsrunButtonPushed(app, event);
            app.FindcellsandcellwallsLamp.Color = [0.00,1.00,0.00];
            end
            
            %6.run correlate script
            if app.correlatebox.Value == 1 
                o6_correlaterunButtonPushed(app,event);
            app.CorrelateEMtoLMLamp.Color = [0.00,1.00,0.00];
            end
            
            
        end

        % Button pushed function: browseButton_3
        function browseButton_3Pushed(app, event)
            app.workdir.value = uigetdir;
            app.GriddirEditField.Value = app.workdir.value;
        end

        % Button pushed function: browseButton_4
        function browseButton_4Pushed(app, event)
            app.ilasticsetup.Value = uigetdir;
            app.ilastiksetupfolderEditField.Value = ['"' app.ilasticsetup.Value '"'];
        end

        % Callback function
        function circ_minEditFieldValueChanged(app, event)
            app.circ.value = app.MincicularityEditField.Value;
            
        end

        % Button pushed function: o6_correlaterun
        function o6_correlaterunButtonPushed(app, event)
            global curr_status;
            
            for n=1 :app.NumberofsquaresEditField.Value
                 if app.RunonlycurrentsquareCheckBox.Value==1
                     n=app.NumberofsquaresEditField.Value;
                     curr_status = [curr_status, sprintf('Starting correlating square %d\n', n)];
                     app.StatusTextArea.Value = curr_status;
                     curr_status = [curr_status, sprintf('%s\n', muclem_correlate(n, app.GriddirEditField.Value,...
                         app.ReduceEMEditField.Value, app.debugcorrelationCheckBox.Value, app.TransformDropDown.Value,...
                         app.NormalizeLMCheckBox.Value, app.LMfilerootEditField.Value, app.AlsodoEMLMCheckBox.Value, app.FlipLMCheckBox.Value))];
                     app.StatusTextArea.Value = curr_status;
                     return
                 else
                     curr_status = [curr_status, sprintf('Starting correlating square %d\n', n)];
                     app.StatusTextArea.Value = curr_status;
                         curr_status = [curr_status, sprintf('%s\n', muclem_correlate(n, app.GriddirEditField.Value,...
                         app.ReduceEMEditField.Value, app.debugcorrelationCheckBox.Value, app.TransformDropDown.Value,...
                         app.NormalizeLMCheckBox.Value, app.LMfilerootEditField.Value, app.AlsodoEMLMCheckBox.Value, app.FlipLMCheckBox.Value))];
                     app.StatusTextArea.Value = curr_status;
                 end
            end
            app.CorrelateEMtoLMLamp.Color = [0.00,1.00,0.00];
            
        end

        % Button pushed function: o7_ClassifyCells
        function o7_ClassifyCellsButtonPushed(app, event)
            global curr_status;
            chlist = str2num(app.touseandtheirorderEditField.Value);
            slist = str2num(app.SquarestoanalyzeEditField.Value);
            curr_status = [curr_status, sprintf('Starting classification squares:%s\n', app.SquarestoanalyzeEditField.Value)];
            app.StatusTextArea.Value = curr_status;
            curr_status = [curr_status, sprintf('%s\n', muclem_read_barcode(slist, chlist, app.ofclustersEditField.Value,...
                app.GriddirEditField.Value, app.ReduceEMEditField.Value, app.mrcfilerootEditField.Value, app.SubtractbackgroundCheckBox.Value,...
                app.RumEditField.Value, app.ShowimagesCheckBox.Value, app.OutputIDtablenameEditField.Value))];
            app.StatusTextArea.Value = curr_status;
            app.ClassifycellsLamp.Color = [0.00,1.00,0.00];
        end

        % Callback function
        function custom_normEditFieldValueChanged(app, event)
            app.norm.value = app.custom_normEditField.Value;
            
        end

        % Button pushed function: o2_cutforilastikrun
        function o2_cutforilastikrunButtonPushed(app, event)
            global curr_status;
            
            for i=1 : app.NumberofsquaresEditField.Value
                 if app.RunonlycurrentsquareCheckBox.Value ==1
                    i =app.NumberofsquaresEditField.Value;
                    curr_status = [curr_status, sprintf('Starting cutting of square %d\n', i)];
                    [app.StatusTextArea.Value] = curr_status;
                    curr_status = [curr_status, sprintf('%s\n', muclem_cutout(i,app.TopLeftXEditField.Value,app.TopLeftYEditField.Value,...
                        app.SizeEditField.Value, app.InteractivecutCheckBox.Value, app.GriddirEditField.Value))];
                    [app.StatusTextArea.Value] = curr_status;
                 return
                 else
                   curr_status = [curr_status sprintf('Starting cutting of square %d\n', i)];
                   [app.StatusTextArea.Value] = curr_status;
                   curr_status = [curr_status sprintf('%s\n', muclem_cutout(i,app.TopLeftXEditField.Value,app.TopLeftYEditField.Value,...
                        app.SizeEditField.Value, app.InteractivecutCheckBox.Value, app.GriddirEditField.Value))];
                   [app.StatusTextArea.Value] = curr_status;
                 end
             end
             app.MakepiecesfortrainingilastikLamp.Color = [0.00,1.00,0.00];
        end

        % Callback function
        function cygwinButtonPushed(app, event)
            cd(userpath);
            !cygwin_installer_0117.exe
        end

        % Value changed function: debugcorrelationCheckBox
        function debugcorrelationCheckBoxValueChanged(app, event)
            
        end

        % Button pushed function: o5_findcellsrun
        function o5_findcellsrunButtonPushed(app, event)
            global curr_status;
            show_more = app.ShowallintermediateimagesCheckBox.Value;
            
            app.XYtoStageTransformDropDown.Value
            
            for i=1:app.NumberofsquaresEditField.Value
                 if app.RunonlycurrentsquareCheckBox.Value==1
                    i =app.NumberofsquaresEditField.Value;
                    curr_status = [curr_status, sprintf('Starting Find-cells on square %d\n', i)];
                    app.StatusTextArea.Value = curr_status;
                    curr_status = [curr_status, sprintf('%s\n', muclem_segment_montage(...
                    i, app.ReduceEMEditField.Value, app.Holesizem2EditField.Value, app.HforwatershedEditField.Value,...
                    app.MinAream2EditField.Value, app.MincicularityEditField.Value,app.GriddirEditField.Value,... 
                    app.mrcfilerootEditField.Value,app.CellsEditField.Value, app.HolesEditField.Value,... 
                    app.BlackEditField.Value, show_more, app.SmoothingRnmEditField.Value, app.DilationRnmEditField.Value))];
                    app.StatusTextArea.Value = curr_status;
                    return
                 else
                    curr_status = [curr_status, sprintf('Starting Find-cells on square %d\n', i)];
                    app.StatusTextArea.Value = curr_status;
                    curr_status = [curr_status, sprintf('%s\n', muclem_segment_montage(...
                    i, app.ReduceEMEditField.Value, app.Holesizem2EditField.Value, app.HforwatershedEditField.Value,...
                    app.MinAream2EditField.Value, app.MincicularityEditField.Value,app.GriddirEditField.Value,... 
                    app.mrcfilerootEditField.Value,app.CellsEditField.Value, app.HolesEditField.Value,... 
                    app.BlackEditField.Value, show_more, app.SmoothingRnmEditField.Value, app.DilationRnmEditField.Value))];
                    app.StatusTextArea.Value = curr_status;
                 end
            end
            app.FindcellsandcellwallsLamp.Color = [0.00,1.00,0.00];
            
        end

        % Callback function
        function hbEditFieldValueChanged(app, event)
            app.hb.value = app.hbEditField.Value;
            
        end

        % Callback function
        function holesizeEditFieldValueChanged(app, event)
            app.holesize.value = app.Holesizem2EditField.Value;
            
        end

        % Callback function
        function ilastikButtonPushed(app, event)
            cd(userpath);
            !ilastik-1.2.0-win64.exe
        end

        % Callback function
        function ilastikfolderEditFieldValueChanged(app, event)
            app.ilastikfolder.value = app.ilastikfolderEditField.Value;
            
        end

        % Callback function
        function ilastiksetupfolderEditFieldValueChanged(app, event)
            app.ilasticsetup.value = app.ilastiksetupfolderEditField.Value;
            
        end

        % Callback function
        function imodButtonPushed(app, event)
           cd(userpath);
           !imod_4.9.0_win64_CUDA6.0.exe
        end

        % Callback function
        function in_navEditFieldValueChanged(app, event)
            app.innev.value = app.InputNavigatornameEditField.Value;
            
        end

        % Callback function
        function lbEditFieldValueChanged(app, event)
            app.lb.value = app.TopLeftXEditField.Value;
            
        end

        % Button pushed function: blendonWindowsButton
        function blendonWindowsButtonPushed(app, event)
           muclem_autoblend_win()
        end

        % Callback function
        function mbEditFieldValueChanged(app, event)
            app.mb.value = app.TopLeftYEditField.Value;
            
        end

        % Callback function
        function mboEditFieldValueChanged(app, event)
            app.mbo.value = app.mboEditField.Value;
            
        end

        % Callback function
        function mrc_root(app, event)
            app.mrc.value = app.mrcfilerootEditField.Value;
            %disp(app.mrc.value)
        end

        % Callback function
        function openButtonPushed(app, event)
            opencropf = sprintf('%s%s%d%s%s',app.GriddirEditField.Value,app.EMfilerootEditField.Value,app.NumberofsquaresEditField.Value,filesep,app.cropfoldernameEditField.Value)
            winopen(opencropf)
        end

        % Callback function
        function out_navEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function r1EditFieldValueChanged(app, event)
            
        end

        % Callback function
        function r2EditFieldValueChanged(app, event)
            
        end

        % Callback function
        function rootEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function roote(app, event)
            
        end

        % Button pushed function: o4_runilastikrun
        function o4_runilastikrunButtonPushed(app, event)
           %cd  Substitutes myclem_run_ilastik_linux_friendly script
           % If many squares are run, there is a checkbox to use .ilp from the first square for all of them
            global curr_status;
            if ispc==1
            
               runfile = 'run-ilastik.bat';
            elseif isunix ==1
                runfile = 'run_ilastik.sh';
            end
            
            runcomm = [app.ilastiksetupfolderEditField.Value, filesep, runfile];
            wd = app.GriddirEditField.Value;
            use_first = app.UsefirstilpforallCheckBox.Value;
            
            for n=1:app.NumberofsquaresEditField.Value
                
                if app.RunonlycurrentsquareCheckBox.Value==1
                    n=app.NumberofsquaresEditField.Value;
                    projname = [wd, filesep, '4ilastik_sq', num2str(n), filesep, 'sq', num2str(n), '.ilp'];
                    in_tiff = [wd, filesep, 'sq', num2str(n), filesep, 'sq', num2str(n), '.tif'];
                    run_this = sprintf('%s --headless --project=%s --output_format=tif --export_source="Simple Segmentation" %s', runcomm, projname, in_tiff);
                    system(run_this);
                    curr_status = [curr_status sprintf('Done with square %d\n', n)];
                    app.StatusTextArea.Value = curr_status;
                    
                    return
                else
                    if use_first==1
                        projname = [wd, filesep, '4ilastik_sq', num2str(1), filesep, 'sq', num2str(1), '.ilp'];
                    else
                        projname = [wd, filesep, '4ilastik_sq', num2str(n), filesep, 'sq', num2str(n), '.ilp'];
                    end
                    in_tiff = [wd, filesep, 'sq', num2str(n), filesep, 'sq', num2str(n), '.tif'];
                    run_this = sprintf('%s --headless --project=%s --output_format=tif --export_source="Simple Segmentation" %s', runcomm, projname, in_tiff);
                    system(run_this);
                    curr_status = [curr_status sprintf('Done with square %d\n', n)];
                    app.StatusTextArea.Value = curr_status;
                    
                end
            end
           
            app.RunilastikLamp.Color = [0.00,1.00,0.00];
            
        end

        % Callback function
        function saveButtonValueChanged(app, event)
                     
        end

        % Callback function
        function selectidentityexcelButtonPushed(app, event)
            
        end

        % Callback function
        function stagecentnameEditFieldValueChanged(app, event)
            app.stagen.value = app.stagecentroidsfileEditField.Value;
            
        end

        % Button pushed function: o3_trainilastikrun
        function o3_trainilastikrunButtonPushed(app, event)
            % this part selects script for current OS and creates a new project in a directory for current square
            global curr_status;
            
            if ispc==1
                runfile = 'run-ilastik.bat';
            elseif isunix ==1
                runfile = 'run_ilastik.sh';
            end
            for n=1:app.NumberofsquaresEditField.Value
                if app.RunonlycurrentsquareCheckBox.Value==1
                    curr_status = [curr_status sprintf('Starting ilastik training using data from square %d\n', n)];
                    app.StatusTextArea.Value = curr_status;
                    n=app.NumberofsquaresEditField.Value;
                    proj_name = [app.GriddirEditField.Value, filesep, '4ilastik_sq', num2str(n), filesep, 'sq', num2str(n), '.ilp'];
                    ri = sprintf('%s%s%s --new_project %s --workflow "Pixel Classification"',app.ilastiksetupfolderEditField.Value, filesep, runfile, proj_name);
                    system(ri);
                    curr_status = [curr_status sprintf('Training done for square %d', n)];
                    app.StatusTextArea.Value = curr_status;
                    return
                else
                    curr_status = [curr_status sprintf('Starting ilastik training using data from square %d\n', n)];
                    app.StatusTextArea.Value = curr_status;
                    proj_name = [app.GriddirEditField.Value, filesep, '4ilastik_sq', num2str(n), filesep, 'sq', num2str(n), '.ilp'];
                    ri = sprintf('%s%s%s --new_project %s --workflow "Pixel Classification"',app.ilastiksetupfolderEditField.Value, filesep, runfile, proj_name);
                    system(ri);
                    curr_status = [curr_status sprintf('Training done for square %d', n)];
                    app.StatusTextArea.Value = curr_status;
                end
                
            end
            
            app.TrainilastikLamp.Color = [0.00,1.00,0.00];
                        
        end

        % Callback function
        function traininghelpButtonPushed(app, event)
            imshow('ilastikconfig.jpg')
        end

        % Callback function
        function transformtypeEditFieldValueChanged(app, event)
            app.transf1.value = app.cellfindingtransformationEditField.Value;
            
        end

        % Callback function
        function transformtypecEditFieldValueChanged(app, event)
            app.transf2.value = app.correlationtransformationEditField.Value;
            
        end

        % Callback function
        function vararginEditFieldValueChanged(app, event)
            app.var.value = app.vararginEditField.Value;
            
        end

        % Callback function
        function work_dirEditFieldValueChanged(app, event)
            app.workdir.value = app.GriddirEditField.Value;
            
        end

        % Button pushed function: o10_writenavfilerun
        function o10_writenavfilerunButtonPushed(app, event)
            global curr_status;
            slist = str2num(app.SquarestoaddEditField.Value);
            curr_status = [curr_status, sprintf('Starting navigator import, squares:%s\n', app.SquarestoaddEditField.Value)];
            app.StatusTextArea.Value = curr_status;
            
            switch app.ImportmodeDropDown.Value
                case 'Transform'
                    addmode=1;
                case 'CoordsInMap'
                    addmode=2;
                case 'CoordsInPieces'
                    addmode=3;
            end
            
            muclem_write_navigator(app.GriddirEditField.Value, app.InputNavigatornameEditField.Value,...
                app.OutputNavigatornameEditField.Value,...
                slist, app.DBTableEditField_nav.Value, app.IncludedCheckBox.Value, ...
                app.ApprovedCheckBox.Value, app.mrcfilerootEditField.Value, app.ReduceEMEditField.Value,...
                app.CCscaledownEditField.Value, addmode,...
                app.XYtoStageTransformDropDown.Value, app.AddAcquireCheckBox.Value);
            curr_status = [curr_status, sprintf('Finished navigator import for squares:%s\n', app.SquarestoaddEditField.Value)];
            app.StatusTextArea.Value = curr_status;
            
            
        end

        % Callback function
        function wshHEditFieldValueChanged(app, event)
            app.wsh.value = app.HforwatershedEditField.Value;
            
        end

        % Button pushed function: ButtonHelpDilationR
        function ButtonHelpDilationRPushed(app, event)
            app.HelpTextArea.Value = 'This parameter determines how thick will be cell wall outlines used for fluorescence measurement. 200 nm is rather thin, 400 is thick. It is preferable to have thicker masks, so that signal is measured even if correlation is not very precise. Intersections with neighbouring cells will be corrected anyway.';
        end

        % Button pushed function: ButtonHelpDilationR_2
        function ButtonHelpDilationShowPushed(app, event)
            app.HelpTextArea.Value = 'All intermediate processing steps will be shown for segmentation workflow. This is useful to find out what''s wrong. For one square around 14 images are shown.';
        end

        % Button pushed function: RunCropCells
        function RunCropCellsButtonPushed(app, event)
            global curr_status;
            
            chlist = str2num(app.touseandtheirorderEditField.Value);
            
            curr_status = [curr_status sprintf('%s\n', muclem_prep_view(chlist, app.GriddirEditField.Value,...
                app.RumEditField.Value, app.PanelsizeEditField.Value, app.ReduceEMEditField.Value, app.mrcfilerootEditField.Value))];
            app.StatusTextArea.Value = curr_status;
            
        end

        % Button pushed function: BrowseSelectrun
        function BrowseSelectrunButtonPushed(app, event)
            global curr_status;
            chlist = str2num(app.touseandtheirorderEditField.Value);
            curr_status = [curr_status sprintf('%s\n', muclem_browser(app.GriddirEditField.Value, app.PanelsizeEditField.Value,...
                app.NrowsEditField.Value, chlist))];
            app.StatusTextArea.Value = curr_status;
        
        end

        % Callback function
        function HelpFLMeasureModeButtonPushed(app, event)
            app.HelpTextArea.Value = 'Measure median fluorescent intensity of the whole cell wall (''simple median''), or split cell walls in small patches (size determined by Patch size parameter below), measure and classify patches, and decide which class the cell belongs basing on highest number of patches with this class (''patches''). The latter method seems to help a little bit against bright aggregates, but takes significantly longer time';
        end

        % Callback function
        function HelpNumClustersButtonPushed(app, event)
            app.HelpTextArea.Value = 'Input to the k-means which will split all data in exact this number of clusters. Should correspond to number of staining combinations (barcodes) used. If some class is less abundant it''s unlikely to be detected. Instead it will stick to a big class, and some other big class will be split in two. Try different number of classes around the target';
        end

        % Callback function
        function HelpPatchSizeButtonPushed(app, event)
            app.HelpTextArea.Value = 'Size of cell wall patch to be measured in ''patches'' mode. In pixels of the montage scaled by the ''ReduceEM'' factor. Open transformed light micrographs in ImageJ, overlay with saved cell wall masks, draw a patch which is ~1/20 of a large cell wall, measure its area in pixels to estimate this parameter.'; 
        end

        % Button pushed function: HelpSubtractBG
        function HelpSubtractBGButtonPushed(app, event)
            app.HelpTextArea.Value = 'If ''Subtract background'' is ticked, will apply a method similar to Imagej rolling ball to all channels. Important if the LM is not very good quality, especially in blue. Radius is size of the element in microns. It should be larger than cell walls measured. If ''show images'' is ticked will show the result of the subtraction for the first square in the list, for all fluorescent channels';
        end

        % Button pushed function: HelpFlChannelsToUse
        function HelpFlChannelsToUseButtonPushed(app, event)
            app.HelpTextArea.Value = 'Comma-separated list of fluorescent channels to use for measurements. Number is its order in the tiff stack. If you have stack ordered R,O,G,B and you want to use all channels except green, enter 1,2,4; if you want it to be displayed in reverse order enter 4,2,1';
        end

        % Button pushed function: HelpImportMode
        function HelpImportModeButtonPushed(app, event)
            app.HelpTextArea.Value = 'Determines how cell coordinates will be imported to the navigator. ''Transform'' mode uses tile coordinates on stage and in montage to calculate transformation. This method is not very precise but only this method is available for SerialEM below 3.7. To aid the manual correction on the scope, images of the squares with numbered cells will be saved. ''CoordsInMap'' method is more precise and available from SerialEM 3.7. ''CoordsInPieces'' is the most precise, availbale from 3.7. Use it if you have SerialEM 3.7';
        end

        % Button pushed function: HelpAddAcquire
        function HelpAddAcquireButtonPushed(app, event)
            app.HelpTextArea.Value = 'Add ''Acquire'' tag to each added navigator item. Use it! (depends on how will you acquire data, but it seems this is the easiest way)';
        end

        % Callback function
        function HelpSelFileButtonPushed(app, event)
            app.HelpTextArea.Value = 'Name of the file (with extension) containing 0/1 verctor specifying for each cell to be imported or not';
        end

        % Button pushed function: HelpSqAnalyse
        function HelpSqAnalyseButtonPushed(app, event)
            global curr_status;
            helpstr = 'Comma-separated list of squares to process in this section. Classification will be made on all cells from these squares together';
            curr_status = [curr_status, sprintf('%s\n', helpstr)];
            app.StatusTextArea.Value = curr_status;
            app.HelpTextArea.Value = helpstr;
        end

        % Button pushed function: HelpIDtableName
        function HelpIDtableNameButtonPushed(app, event)
          global curr_status;
            helpstr = 'Name of the table (WITHOUT extension) where results of classification will be stored';
            curr_status = [curr_status, sprintf('%s\n', helpstr)];
            app.StatusTextArea.Value = curr_status;
            app.HelpTextArea.Value = helpstr;  
        end

        % Button pushed function: HelpNavIn
        function HelpNavInButtonPushed(app, event)
            global curr_status;
            helpstr = 'Name of the navigator file to read (with extension). It will be copied and new items added to the navigator with new name specified in ''Output navigator name'' field';
            curr_status = [curr_status, sprintf('%s\n', helpstr)];
            app.StatusTextArea.Value = curr_status;
            app.HelpTextArea.Value = helpstr;  
        end

        % Button pushed function: HelpSqtoNav
        function HelpSqtoNavButtonPushed(app, event)
            global curr_status;
            helpstr = 'Comma-separated list of squares. Cells from one square will have the same group ID in the navigator file.';
            curr_status = [curr_status, sprintf('%s\n', helpstr)];
            app.StatusTextArea.Value = curr_status;
            app.HelpTextArea.Value = helpstr;  
        
        end

        % Button pushed function: AssignbarcodesRun
        function AssignbarcodesRunButtonPushed(app, event)
            global curr_status;
            chlist = str2num(app.touseandtheirorderEditField.Value);
            curr_status = [curr_status sprintf('%s', muclem_assign_labels(app.GriddirEditField.Value, chlist, ...
                app.PanelsizeEditField.Value, app.NrowsEditField.Value))];
            app.StatusTextArea.Value = curr_status;
            
            
        end

        % Button pushed function: HelpCropSize
        function HelpCropSizeButtonPushed(app, event)
             app.HelpTextArea.Value = 'Size of the region around cropped cells in microns. Around 6 is good - any cell will fit.';
        end

        % Button pushed function: HelpResizing
        function HelpResizingButtonPushed(app, event)
            app.HelpTextArea.Value = 'Factor of resizing for the cropped images relative to original size. Since the original size is the size of LM data correlated to EM, images can get rather big. Later they are displayed as small panels, so already redcing them saves space. Factor of 0.5 is ok. This reduces both LM and EM images.';
        end

        % Button pushed function: HelpPanelSize
        function HelpPanelSizeButtonPushed(app, event)
            app.HelpTextArea.Value = 'Size of the panel (in pixels) where each cell image is dispalyed during evaluation steps (9. and 10.). Should be adjusted together with the number of rows (below) to get the optimal display of cell images and text in the figure window (because text size is not easily scaled). 80-100 pixels is ok.';
        end

        % Button pushed function: HelpNrows
        function HelpNrowsButtonPushed(app, event)
            app.HelpTextArea.Value = 'Number of rows (cells) displayed at the same time during evaluation steps (9.  and 10.). Must be from 1 to 12. Should be adjusted for your screen together with panel size (above) to get optimal display of text and images.'
        end

        % Button pushed function: HelpDBtable
        function HelpDBtableButtonPushed(app, event)
            app.HelpTextArea.Value = 'DB table will be automatically used for adding cells to the navigator file if the DBtable name entered in the field exists AND at least one of the checkboxes (Approved or Included) is selected. If so, the script will look in the DB table for the squares listed in the pipeline tab for inclusion. Depending on the checkbox, Only cells marked as not excluded or as approved or as both(&) will be added. If no DB table is used, all cells from requested squares are added.';
        end

        % Value changed function: autoloadCheckBox
        function autoloadCheckBoxValueChanged(app, event)
            global htemstartup_start
            
            if app.autoloadCheckBox.Value==1 
            htemstartup_start =1;
            elseif app.autoloadCheckBox.Value==0
            htemstartup_start =0;
            end
            startup_status=[userpath filesep 'htemstartup.mat'];
            save(startup_status,'htemstartup_start')
        end

        % Button pushed function: savesetingsButton
        function savesetingsButtonPushed(app, event)
            [FileName,PathName] = uiputfile('*.mat','Select mat file');
            if FileName==0
            defoltpath = [userpath,filesep,'htemseting.mat'];
            save(defoltpath)
            curr_status = ['setting saved to: ' defoltpath] ;
            else
            save(fullfile(PathName,FileName))
            curr_status = ['setting saved to: ' fullfile(PathName,FileName)] ;
            end
            app.HelpTextArea.Value = curr_status;
        end

        % Button pushed function: loadsetings
        function loadsetingsButtonPushed(app, event)
             %load setting mat file 
            [FileName,PathName] = uigetfile('*.mat','Select mat file');
            if FileName==0
            defoltpath = [userpath,filesep,'htemseting.mat'];
            temp = load(defoltpath,'app');
            else
            temp = load(fullfile(PathName,FileName),'app');
            end
            %we need to add all 
           app.GriddirEditField.Value = temp.app.GriddirEditField.Value;
           app.ilastiksetupfolderEditField.Value = temp.app.ilastiksetupfolderEditField.Value;
           app.LMfilerootEditField.Value = temp.app.LMfilerootEditField.Value;
           app.mrcfilerootEditField.Value = temp.app.mrcfilerootEditField.Value;
           app.ReduceEMEditField.Value = temp.app.ReduceEMEditField.Value;
           app.TopLeftXEditField.Value = temp.app.TopLeftXEditField.Value;
           app.TopLeftYEditField.Value = temp.app.TopLeftYEditField.Value;
           app.SizeEditField.Value = temp.app.SizeEditField.Value;
           app.Holesizem2EditField.Value = temp.app.Holesizem2EditField.Value;
           app.MinAream2EditField.Value = temp.app.MinAream2EditField.Value;
           app.MincicularityEditField.Value = temp.app.MincicularityEditField.Value;
           app.HforwatershedEditField.Value = temp.app.HforwatershedEditField.Value;
           app.SmoothingRnmEditField.Value = temp.app.SmoothingRnmEditField.Value;
           app.DilationRnmEditField.Value = temp.app.DilationRnmEditField.Value;
           app.CellsEditField.Value = temp.app.CellsEditField.Value;
           app.HolesEditField.Value = temp.app.HolesEditField.Value;
            app.ResinEditField.Value = temp.app.ResinEditField.Value;
            app.BlackEditField.Value = temp.app.BlackEditField.Value;
            app.RumEditField.Value = temp.app.RumEditField.Value;
            app.touseandtheirorderEditField.Value = temp.app.touseandtheirorderEditField.Value;
            app.ofclustersEditField.Value = temp.app.ofclustersEditField.Value;
            app.CropsizemEditField.Value = temp.app.CropsizemEditField.Value;
            app.ResizingEditField.Value = temp.app.ResizingEditField.Value;
            app.PanelsizeEditField.Value = temp.app.PanelsizeEditField.Value;
            app.NrowsEditField.Value = temp.app.NrowsEditField.Value;
            
            drawnow   
            
        end

        % Button pushed function: runfinalcrop
        function runfinalcropButtonPushed(app, event)
            global curr_status;
            %i changed the sq list from SquarestoaddEditField to SquarestoanalyzeEditField
            slist = str2num(app.SquarestoanalyzeEditField.Value);
            chlist = str2num(app.touseandtheirorderEditField.Value);
            curr_status = [curr_status sprintf('%s\n', ...
                muclem_export_mediummag(app.DBtableEditField.Value,...
                chlist, app.GriddirEditField.Value,...
                app.RumEditField.Value, app.CropsizemEditField.Value, ...
                app.ResinEditField.Value, app.ReduceEMEditField.Value, ...
                app.mrcfilerootEditField.Value, app.IncludedCheckBox_2.Value, ...
                app.ApprovedCheckBox_2.Value))];
                app.StatusTextArea.Value = curr_status;
          
        end

        % Button pushed function: browseButton_6
        function browseButton_6Pushed(app, event)
            curr_dir = pwd;
            work_dir = app.GriddirEditField.Value;
            if ~isempty(work_dir)
                cd(work_dir);
            else
                disp('No work dir')
            end
            [DBfile, DBpath, Fltridx] = uigetfile('*.txt');
            if DBfile==0
                cd(curr_dir);
                return
            end
            app.DBtableEditField.Value = [DBpath, DBfile];
            cd(curr_dir)
        end

        % Button pushed function: browseButton_7
        function browseButton_7Pushed(app, event)
            curr_dir = pwd;
            work_dir = app.GriddirEditField.Value;
            if ~isempty(work_dir)
                cd(work_dir);
            else
                disp('No work dir')
            end
            [DBfile, DBpath, Fltridx] = uigetfile('*.txt');
            if DBfile==0
                cd(curr_dir);
                return
            end
            app.DBTableEditField_nav.Value = DBfile;
            cd(curr_dir)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MultiCLEMAnalyzerUIFigure = uifigure;
            app.MultiCLEMAnalyzerUIFigure.Name = 'MultiCLEM Analyzer';
            setAutoResize(app, app.MultiCLEMAnalyzerUIFigure, true)
            app.TabGroup = uitabgroup(app.MultiCLEMAnalyzerUIFigure);
            app.PipelineTab = uitab(app.TabGroup);
            app.PipelineTab.Title = 'Pipeline';
            app.PipelineTab.Units = 'pixels';
            app.EXITButton_2 = uibutton(app.PipelineTab, 'push');
            app.EXITButton_2.ButtonPushedFcn = createCallbackFcn(app, @EXITButton_2Pushed, true);
            app.EXITButton_2.Text = 'EXIT';
            app.StatusTextAreaLabel = uilabel(app.PipelineTab);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.PipelineTab);
            app.StatusTextArea.Value = {' '};
            app.DataPreparationPanel = uipanel(app.PipelineTab);
            app.DataPreparationPanel.Title = 'Data Preparation';
            app.blendonWindowsButton = uibutton(app.DataPreparationPanel, 'push');
            app.blendonWindowsButton.ButtonPushedFcn = createCallbackFcn(app, @blendonWindowsButtonPushed, true);
            app.blendonWindowsButton.Text = 'blend on Windows';
            app.autoblendbox = uicheckbox(app.DataPreparationPanel);
            app.autoblendbox.ValueChangedFcn = createCallbackFcn(app, @autoblendboxValueChanged, true);
            app.autoblendbox.Text = '';
            app.cutforilastikbox = uicheckbox(app.DataPreparationPanel);
            app.cutforilastikbox.Text = '';
            app.trainilastikbox = uicheckbox(app.DataPreparationPanel);
            app.trainilastikbox.Text = '';
            app.runilastikbox = uicheckbox(app.DataPreparationPanel);
            app.runilastikbox.Text = '';
            app.findcellsbox = uicheckbox(app.DataPreparationPanel);
            app.findcellsbox.Text = '';
            app.correlatebox = uicheckbox(app.DataPreparationPanel);
            app.correlatebox.Text = '';
            app.RunonlycurrentsquareCheckBox = uicheckbox(app.DataPreparationPanel);
            app.RunonlycurrentsquareCheckBox.Text = 'Run only current square';
            app.InteractivecutCheckBox = uicheckbox(app.DataPreparationPanel);
            app.InteractivecutCheckBox.Text = 'Interactive cut';
            app.UsefirstilpforallCheckBox = uicheckbox(app.DataPreparationPanel);
            app.UsefirstilpforallCheckBox.Text = 'Use first .ilp for all';
            app.NumberofsquaresEditFieldLabel = uilabel(app.DataPreparationPanel);
            app.NumberofsquaresEditFieldLabel.Text = 'Number of squares';
            app.NumberofsquaresEditField = uieditfield(app.DataPreparationPanel, 'numeric');
            app.NumberofsquaresEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofsquaresEditFieldValueChanged, true);
            app.NumberofsquaresEditField.ValueDisplayFormat = '%.0f';
            app.NumberofsquaresEditField.Value = 1;
            app.o1_autoblendrun = uibutton(app.DataPreparationPanel, 'push');
            app.o1_autoblendrun.ButtonPushedFcn = createCallbackFcn(app, @o1_autoblendrunButtonPushed, true);
            app.o1_autoblendrun.Text = 'run';
            app.BlendmontageLampLabel = uilabel(app.DataPreparationPanel);
            app.BlendmontageLampLabel.Text = '1. Blend montage';
            app.BlendmontageLamp = uilamp(app.DataPreparationPanel);
            app.o2_cutforilastikrun = uibutton(app.DataPreparationPanel, 'push');
            app.o2_cutforilastikrun.ButtonPushedFcn = createCallbackFcn(app, @o2_cutforilastikrunButtonPushed, true);
            app.o2_cutforilastikrun.Text = 'run';
            app.MakepiecesfortrainingilastikLampLabel = uilabel(app.DataPreparationPanel);
            app.MakepiecesfortrainingilastikLampLabel.Text = '2. Make pieces for training ilastik';
            app.MakepiecesfortrainingilastikLamp = uilamp(app.DataPreparationPanel);
            app.o3_trainilastikrun = uibutton(app.DataPreparationPanel, 'push');
            app.o3_trainilastikrun.ButtonPushedFcn = createCallbackFcn(app, @o3_trainilastikrunButtonPushed, true);
            app.o3_trainilastikrun.Text = 'run';
            app.TrainilastikLampLabel = uilabel(app.DataPreparationPanel);
            app.TrainilastikLampLabel.Text = '3. Train ilastik';
            app.TrainilastikLamp = uilamp(app.DataPreparationPanel);
            app.o4_runilastikrun = uibutton(app.DataPreparationPanel, 'push');
            app.o4_runilastikrun.ButtonPushedFcn = createCallbackFcn(app, @o4_runilastikrunButtonPushed, true);
            app.o4_runilastikrun.Text = 'run';
            app.RunilastikLabel = uilabel(app.DataPreparationPanel);
            app.RunilastikLabel.Text = '4. Run ilastik';
            app.RunilastikLamp = uilamp(app.DataPreparationPanel);
            app.o5_findcellsrun = uibutton(app.DataPreparationPanel, 'push');
            app.o5_findcellsrun.ButtonPushedFcn = createCallbackFcn(app, @o5_findcellsrunButtonPushed, true);
            app.o5_findcellsrun.Text = 'run';
            app.FindcellsandcellwallsLampLabel = uilabel(app.DataPreparationPanel);
            app.FindcellsandcellwallsLampLabel.Text = '5. Find cells and cell walls';
            app.FindcellsandcellwallsLamp = uilamp(app.DataPreparationPanel);
            app.o6_correlaterun = uibutton(app.DataPreparationPanel, 'push');
            app.o6_correlaterun.ButtonPushedFcn = createCallbackFcn(app, @o6_correlaterunButtonPushed, true);
            app.o6_correlaterun.Text = 'run';
            app.CorrelateEMtoLMLabel = uilabel(app.DataPreparationPanel);
            app.CorrelateEMtoLMLabel.Text = '6. Correlate EM to LM';
            app.CorrelateEMtoLMLamp = uilamp(app.DataPreparationPanel);
            app.autorunButton = uibutton(app.DataPreparationPanel, 'push');
            app.autorunButton.ButtonPushedFcn = createCallbackFcn(app, @autorunButtonPushed, true);
            app.autorunButton.Text = 'auto run';
            app.Label = uilabel(app.DataPreparationPanel);
            app.Label.Text = '^--------';
            app.BarcodeReadingPanel = uipanel(app.PipelineTab);
            app.BarcodeReadingPanel.Title = 'Barcode Reading';
            app.SquarestoanalyzeEditFieldLabel = uilabel(app.BarcodeReadingPanel);
            app.SquarestoanalyzeEditFieldLabel.Text = 'Squares to analyze';
            app.SquarestoanalyzeEditField = uieditfield(app.BarcodeReadingPanel, 'text');
            app.SquarestoanalyzeEditField.Value = '1,2';
            app.OutputIDtablenameLabel = uilabel(app.BarcodeReadingPanel);
            app.OutputIDtablenameLabel.Text = 'Output ID table name';
            app.OutputIDtablenameEditField = uieditfield(app.BarcodeReadingPanel, 'text');
            app.OutputIDtablenameEditField.Value = 'IDtable1';
            app.o7_ClassifyCells = uibutton(app.BarcodeReadingPanel, 'push');
            app.o7_ClassifyCells.ButtonPushedFcn = createCallbackFcn(app, @o7_ClassifyCellsButtonPushed, true);
            app.o7_ClassifyCells.Text = 'run';
            app.ClassifycellsLabel = uilabel(app.BarcodeReadingPanel);
            app.ClassifycellsLabel.Text = '7. Classify cells';
            app.ClassifycellsLamp = uilamp(app.BarcodeReadingPanel);
            app.HelpSqAnalyse = uibutton(app.BarcodeReadingPanel, 'push');
            app.HelpSqAnalyse.ButtonPushedFcn = createCallbackFcn(app, @HelpSqAnalyseButtonPushed, true);
            app.HelpSqAnalyse.Text = '?';
            app.HelpIDtableName = uibutton(app.BarcodeReadingPanel, 'push');
            app.HelpIDtableName.ButtonPushedFcn = createCallbackFcn(app, @HelpIDtableNameButtonPushed, true);
            app.HelpIDtableName.Text = '?';
            app.ImporttoNavigatorPanel = uipanel(app.PipelineTab);
            app.ImporttoNavigatorPanel.Title = '12. Import to Navigator';
            app.o10_writenavfilerun = uibutton(app.ImporttoNavigatorPanel, 'push');
            app.o10_writenavfilerun.ButtonPushedFcn = createCallbackFcn(app, @o10_writenavfilerunButtonPushed, true);
            app.o10_writenavfilerun.Text = 'Write Navigator';
            app.HelpSqtoNav = uibutton(app.ImporttoNavigatorPanel, 'push');
            app.HelpSqtoNav.ButtonPushedFcn = createCallbackFcn(app, @HelpSqtoNavButtonPushed, true);
            app.HelpSqtoNav.Text = '?';
            app.HelpNavIn = uibutton(app.ImporttoNavigatorPanel, 'push');
            app.HelpNavIn.ButtonPushedFcn = createCallbackFcn(app, @HelpNavInButtonPushed, true);
            app.HelpNavIn.Text = '?';
            app.SquarestoaddEditFieldLabel = uilabel(app.ImporttoNavigatorPanel);
            app.SquarestoaddEditFieldLabel.Text = 'Squares to add';
            app.SquarestoaddEditField = uieditfield(app.ImporttoNavigatorPanel, 'text');
            app.SquarestoaddEditField.Value = '1,2,3';
            app.InputNavigatornameEditFieldLabel = uilabel(app.ImporttoNavigatorPanel);
            app.InputNavigatornameEditFieldLabel.Text = 'Input Navigator name';
            app.InputNavigatornameEditField = uieditfield(app.ImporttoNavigatorPanel, 'text');
            app.InputNavigatornameEditField.Value = 'grid1.nav';
            app.OutputNavigatornameEditFieldLabel = uilabel(app.ImporttoNavigatorPanel);
            app.OutputNavigatornameEditFieldLabel.Text = 'Output Navigator name';
            app.OutputNavigatornameEditField = uieditfield(app.ImporttoNavigatorPanel, 'text');
            app.OutputNavigatornameEditField.Value = 'grid1_sq1-3.nav';
            app.DBTableEditFieldLabel = uilabel(app.ImporttoNavigatorPanel);
            app.DBTableEditFieldLabel.Text = 'DB Table';
            app.DBTableEditField_nav = uieditfield(app.ImporttoNavigatorPanel, 'text');
            app.browseButton_7 = uibutton(app.ImporttoNavigatorPanel, 'push');
            app.browseButton_7.ButtonPushedFcn = createCallbackFcn(app, @browseButton_7Pushed, true);
            app.browseButton_7.Text = 'browse';
            app.EvaluationandQualitycontrolPanel = uipanel(app.PipelineTab);
            app.EvaluationandQualitycontrolPanel.Title = 'Evaluation and Quality control';
            app.BrowseSelectrun = uibutton(app.EvaluationandQualitycontrolPanel, 'push');
            app.BrowseSelectrun.ButtonPushedFcn = createCallbackFcn(app, @BrowseSelectrunButtonPushed, true);
            app.BrowseSelectrun.Text = '10. Browse Dataset';
            app.AssignbarcodesRun = uibutton(app.EvaluationandQualitycontrolPanel, 'push');
            app.AssignbarcodesRun.ButtonPushedFcn = createCallbackFcn(app, @AssignbarcodesRunButtonPushed, true);
            app.AssignbarcodesRun.Text = '9. Assign barcodes';
            app.runfinalcrop = uibutton(app.EvaluationandQualitycontrolPanel, 'push');
            app.runfinalcrop.ButtonPushedFcn = createCallbackFcn(app, @runfinalcropButtonPushed, true);
            app.runfinalcrop.Text = '11. Export cells';
            app.RunCropCells = uibutton(app.EvaluationandQualitycontrolPanel, 'push');
            app.RunCropCells.ButtonPushedFcn = createCallbackFcn(app, @RunCropCellsButtonPushed, true);
            app.RunCropCells.Text = '8. Prepare view';
            app.browseButton_6 = uibutton(app.EvaluationandQualitycontrolPanel, 'push');
            app.browseButton_6.ButtonPushedFcn = createCallbackFcn(app, @browseButton_6Pushed, true);
            app.browseButton_6.Text = 'browse';
            app.DBtableLabel = uilabel(app.EvaluationandQualitycontrolPanel);
            app.DBtableLabel.Text = 'DB table';
            app.DBtableEditField = uieditfield(app.EvaluationandQualitycontrolPanel, 'text');
            app.IncludedCheckBox_2 = uicheckbox(app.EvaluationandQualitycontrolPanel);
            app.IncludedCheckBox_2.Text = 'Included';
            app.IncludedCheckBox_2.Value = true;
            app.ApprovedCheckBox_2 = uicheckbox(app.EvaluationandQualitycontrolPanel);
            app.ApprovedCheckBox_2.Text = 'Approved';
            app.ApprovedCheckBox_2.Value = true;
            app.HelpDBtable_2 = uibutton(app.EvaluationandQualitycontrolPanel, 'push');
            app.HelpDBtable_2.Text = '?';
            app.ExportonlyLabel = uilabel(app.EvaluationandQualitycontrolPanel);
            app.ExportonlyLabel.Text = 'Export only:';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SettingsTab.Units = 'pixels';
            app.EXITButton = uibutton(app.SettingsTab, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.debugcorrelationCheckBox = uicheckbox(app.SettingsTab);
            app.debugcorrelationCheckBox.ValueChangedFcn = createCallbackFcn(app, @debugcorrelationCheckBoxValueChanged, true);
            app.debugcorrelationCheckBox.Text = 'debug correlation';
            app.InputdataPanel = uipanel(app.SettingsTab);
            app.InputdataPanel.Title = 'Input data';
            app.GriddirEditFieldLabel = uilabel(app.InputdataPanel);
            app.GriddirEditFieldLabel.Text = 'Grid dir';
            app.GriddirEditField = uieditfield(app.InputdataPanel, 'text');
            app.GriddirEditField.ValueChangedFcn = createCallbackFcn(app, @GriddirEditFieldValueChanged, true);
            app.ilastiksetupfolderEditFieldLabel = uilabel(app.InputdataPanel);
            app.ilastiksetupfolderEditFieldLabel.Text = 'ilastik setup folder';
            app.ilastiksetupfolderEditField = uieditfield(app.InputdataPanel, 'text');
            app.browseButton_3 = uibutton(app.InputdataPanel, 'push');
            app.browseButton_3.ButtonPushedFcn = createCallbackFcn(app, @browseButton_3Pushed, true);
            app.browseButton_3.Text = 'browse';
            app.browseButton_4 = uibutton(app.InputdataPanel, 'push');
            app.browseButton_4.ButtonPushedFcn = createCallbackFcn(app, @browseButton_4Pushed, true);
            app.browseButton_4.Text = 'browse';
            app.OrganizeLMCheckBox = uicheckbox(app.InputdataPanel);
            app.OrganizeLMCheckBox.Text = 'Organize LM';
            app.LMfilerootEditFieldLabel = uilabel(app.InputdataPanel);
            app.LMfilerootEditFieldLabel.Text = 'LM file root';
            app.LMfilerootEditField = uieditfield(app.InputdataPanel, 'text');
            app.LMfilerootEditField.ValueChangedFcn = createCallbackFcn(app, @LMfilerootEditFieldValueChanged, true);
            app.LMfilerootEditField.Value = 'lm';
            app.mrcfilerootLabel = uilabel(app.InputdataPanel);
            app.mrcfilerootLabel.Text = 'mrc file root';
            app.mrcfilerootEditField = uieditfield(app.InputdataPanel, 'text');
            app.mrcfilerootEditField.Value = 'mm';
            app.ButtonHelpMrcRoot = uibutton(app.InputdataPanel, 'push');
            app.ButtonHelpMrcRoot.ButtonPushedFcn = createCallbackFcn(app, @ButtonHelpMrcRootPushed, true);
            app.ButtonHelpMrcRoot.Text = '?';
            app.ReduceEMEditFieldLabel = uilabel(app.InputdataPanel);
            app.ReduceEMEditFieldLabel.Text = 'Reduce EM';
            app.ReduceEMEditField = uieditfield(app.InputdataPanel, 'numeric');
            app.ReduceEMEditField.Limits = [0 1];
            app.ReduceEMEditField.Value = 0.25;
            app.ButtonHelpReduceEM = uibutton(app.InputdataPanel, 'push');
            app.ButtonHelpReduceEM.ButtonPushedFcn = createCallbackFcn(app, @ButtonHelpReduceEMPushed, true);
            app.ButtonHelpReduceEM.Text = '?';
            app.CutforilastikPanel = uipanel(app.SettingsTab);
            app.CutforilastikPanel.Title = '2. Cut for ilastik';
            app.TopLeftXEditFieldLabel = uilabel(app.CutforilastikPanel);
            app.TopLeftXEditFieldLabel.Text = 'Top Left X';
            app.TopLeftXEditField = uieditfield(app.CutforilastikPanel, 'numeric');
            app.TopLeftXEditField.Value = 5000;
            app.TopLeftYEditFieldLabel = uilabel(app.CutforilastikPanel);
            app.TopLeftYEditFieldLabel.Text = 'Top Left Y';
            app.TopLeftYEditField = uieditfield(app.CutforilastikPanel, 'numeric');
            app.TopLeftYEditField.Value = 6500;
            app.SizeEditFieldLabel = uilabel(app.CutforilastikPanel);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.CutforilastikPanel, 'numeric');
            app.SizeEditField.Value = 1500;
            app.ButtonHelpCut = uibutton(app.CutforilastikPanel, 'push');
            app.ButtonHelpCut.ButtonPushedFcn = createCallbackFcn(app, @ButtonHelpCutPushed, true);
            app.ButtonHelpCut.Text = '?';
            app.FindcellsinEMPanel = uipanel(app.SettingsTab);
            app.FindcellsinEMPanel.Title = '5. Find cells in EM';
            app.Holesizem2EditFieldLabel = uilabel(app.FindcellsinEMPanel);
            app.Holesizem2EditFieldLabel.Text = 'Hole size (µm^2)';
            app.Holesizem2EditField = uieditfield(app.FindcellsinEMPanel, 'numeric');
            app.Holesizem2EditField.Value = 0.5;
            app.MinAream2EditFieldLabel = uilabel(app.FindcellsinEMPanel);
            app.MinAream2EditFieldLabel.Text = 'Min Area (µm^2)';
            app.MinAream2EditField = uieditfield(app.FindcellsinEMPanel, 'numeric');
            app.MinAream2EditField.Value = 1.5;
            app.MincicularityEditFieldLabel = uilabel(app.FindcellsinEMPanel);
            app.MincicularityEditFieldLabel.Text = 'Min cicularity';
            app.MincicularityEditField = uieditfield(app.FindcellsinEMPanel, 'numeric');
            app.MincicularityEditField.Value = 0.8;
            app.HforwatershedEditFieldLabel = uilabel(app.FindcellsinEMPanel);
            app.HforwatershedEditFieldLabel.Text = 'H for watershed';
            app.HforwatershedEditField = uieditfield(app.FindcellsinEMPanel, 'numeric');
            app.HforwatershedEditField.Limits = [1 Inf];
            app.HforwatershedEditField.Value = 1;
            app.ButtonHoleSize = uibutton(app.FindcellsinEMPanel, 'push');
            app.ButtonHoleSize.ButtonPushedFcn = createCallbackFcn(app, @ButtonHoleSizePushed, true);
            app.ButtonHoleSize.Text = '?';
            app.ButtonHelpMinArea = uibutton(app.FindcellsinEMPanel, 'push');
            app.ButtonHelpMinArea.ButtonPushedFcn = createCallbackFcn(app, @ButtonHelpMinAreaPushed, true);
            app.ButtonHelpMinArea.Text = '?';
            app.ButtonHelpMinCirc = uibutton(app.FindcellsinEMPanel, 'push');
            app.ButtonHelpMinCirc.ButtonPushedFcn = createCallbackFcn(app, @ButtonHelpMinCircPushed, true);
            app.ButtonHelpMinCirc.Text = '?';
            app.ButtonHelpHforWatershed = uibutton(app.FindcellsinEMPanel, 'push');
            app.ButtonHelpHforWatershed.ButtonPushedFcn = createCallbackFcn(app, @ButtonHelpHforWatershedPushed, true);
            app.ButtonHelpHforWatershed.Text = '?';
            app.SmoothingRnmEditFieldLabel = uilabel(app.FindcellsinEMPanel);
            app.SmoothingRnmEditFieldLabel.Text = ' Smoothing R (nm)';
            app.SmoothingRnmEditField = uieditfield(app.FindcellsinEMPanel, 'numeric');
            app.SmoothingRnmEditField.Value = 300;
            app.DilationRnmEditFieldLabel = uilabel(app.FindcellsinEMPanel);
            app.DilationRnmEditFieldLabel.Text = 'Dilation R (nm)';
            app.DilationRnmEditField = uieditfield(app.FindcellsinEMPanel, 'numeric');
            app.DilationRnmEditField.Value = 400;
            app.ButtonHelpSmoothing = uibutton(app.FindcellsinEMPanel, 'push');
            app.ButtonHelpSmoothing.ButtonPushedFcn = createCallbackFcn(app, @ButtonHelpSmoothingPushed, true);
            app.ButtonHelpSmoothing.Text = '?';
            app.ShowallintermediateimagesCheckBox = uicheckbox(app.FindcellsinEMPanel);
            app.ShowallintermediateimagesCheckBox.Text = 'Show all intermediate images';
            app.ButtonHelpDilationR = uibutton(app.FindcellsinEMPanel, 'push');
            app.ButtonHelpDilationR.ButtonPushedFcn = createCallbackFcn(app, @ButtonHelpDilationRPushed, true);
            app.ButtonHelpDilationR.Text = '?';
            app.ButtonHelpDilationR_2 = uibutton(app.FindcellsinEMPanel, 'push');
            app.ButtonHelpDilationR_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonHelpDilationShowPushed, true);
            app.ButtonHelpDilationR_2.Text = '?';
            app.IlastiklabelsPanel = uipanel(app.SettingsTab);
            app.IlastiklabelsPanel.Title = 'Ilastik labels';
            app.CellsEditFieldLabel = uilabel(app.IlastiklabelsPanel);
            app.CellsEditFieldLabel.Text = 'Cells';
            app.CellsEditField = uieditfield(app.IlastiklabelsPanel, 'numeric');
            app.CellsEditField.Limits = [1 4];
            app.CellsEditField.Value = 1;
            app.HolesEditFieldLabel = uilabel(app.IlastiklabelsPanel);
            app.HolesEditFieldLabel.Text = 'Holes';
            app.HolesEditField = uieditfield(app.IlastiklabelsPanel, 'numeric');
            app.HolesEditField.Limits = [1 4];
            app.HolesEditField.Value = 2;
            app.ResinEditFieldLabel = uilabel(app.IlastiklabelsPanel);
            app.ResinEditFieldLabel.Text = 'Resin';
            app.ResinEditField = uieditfield(app.IlastiklabelsPanel, 'numeric');
            app.ResinEditField.Limits = [1 4];
            app.ResinEditField.Value = 3;
            app.BlackEditFieldLabel = uilabel(app.IlastiklabelsPanel);
            app.BlackEditFieldLabel.Text = 'Black';
            app.BlackEditField = uieditfield(app.IlastiklabelsPanel, 'numeric');
            app.BlackEditField.Limits = [1 4];
            app.BlackEditField.Value = 4;
            app.EMtoLMcorrelationPanel = uipanel(app.SettingsTab);
            app.EMtoLMcorrelationPanel.Title = '6. EM to LM correlation';
            app.TransformDropDownLabel = uilabel(app.EMtoLMcorrelationPanel);
            app.TransformDropDownLabel.Text = 'Transform';
            app.TransformDropDown = uidropdown(app.EMtoLMcorrelationPanel);
            app.TransformDropDown.Items = {'affine', 'similarity', 'projective'};
            app.TransformDropDown.Value = 'affine';
            app.NormalizeLMCheckBox = uicheckbox(app.EMtoLMcorrelationPanel);
            app.NormalizeLMCheckBox.Text = 'Normalize LM';
            app.NormalizeLMCheckBox.Value = true;
            app.AlsodoEMLMCheckBox = uicheckbox(app.EMtoLMcorrelationPanel);
            app.AlsodoEMLMCheckBox.Text = 'Also do EM->LM';
            app.FlipLMCheckBox = uicheckbox(app.EMtoLMcorrelationPanel);
            app.FlipLMCheckBox.Text = 'Flip LM';
            app.ButtonHelpCorrTransform = uibutton(app.EMtoLMcorrelationPanel, 'push');
            app.ButtonHelpCorrTransform.ButtonPushedFcn = createCallbackFcn(app, @ButtonHelpCorrTransformPushed, true);
            app.ButtonHelpCorrTransform.Text = '?';
            app.ButtonHelpCorrNormLM = uibutton(app.EMtoLMcorrelationPanel, 'push');
            app.ButtonHelpCorrNormLM.ButtonPushedFcn = createCallbackFcn(app, @ButtonHelpCorrNormLMPushed, true);
            app.ButtonHelpCorrNormLM.Text = '?';
            app.ButtonHelpCorrDoEm2LM = uibutton(app.EMtoLMcorrelationPanel, 'push');
            app.ButtonHelpCorrDoEm2LM.ButtonPushedFcn = createCallbackFcn(app, @ButtonHelpCorrDoEm2LMPushed, true);
            app.ButtonHelpCorrDoEm2LM.Text = '?';
            app.ButtonHelpCorrFlipLM = uibutton(app.EMtoLMcorrelationPanel, 'push');
            app.ButtonHelpCorrFlipLM.ButtonPushedFcn = createCallbackFcn(app, @ButtonHelpCorrFlipLMPushed, true);
            app.ButtonHelpCorrFlipLM.Text = '?';
            app.MeasurefluorescentsignalandclassifycellsPanel = uipanel(app.SettingsTab);
            app.MeasurefluorescentsignalandclassifycellsPanel.Title = '7. Measure fluorescent signal and classify cells';
            app.SubtractbackgroundCheckBox = uicheckbox(app.MeasurefluorescentsignalandclassifycellsPanel);
            app.SubtractbackgroundCheckBox.Text = 'Subtract background';
            app.SubtractbackgroundCheckBox.Value = true;
            app.ShowimagesCheckBox = uicheckbox(app.MeasurefluorescentsignalandclassifycellsPanel);
            app.ShowimagesCheckBox.Text = 'Show images';
            app.RumEditFieldLabel = uilabel(app.MeasurefluorescentsignalandclassifycellsPanel);
            app.RumEditFieldLabel.Text = 'R (um)';
            app.RumEditField = uieditfield(app.MeasurefluorescentsignalandclassifycellsPanel, 'numeric');
            app.RumEditField.Value = 3;
            app.FluorescentchannelsLabel = uilabel(app.MeasurefluorescentsignalandclassifycellsPanel);
            app.FluorescentchannelsLabel.Text = 'Fluorescent channels';
            app.touseandtheirorderEditFieldLabel = uilabel(app.MeasurefluorescentsignalandclassifycellsPanel);
            app.touseandtheirorderEditFieldLabel.Text = 'to use and their order';
            app.touseandtheirorderEditField = uieditfield(app.MeasurefluorescentsignalandclassifycellsPanel, 'text');
            app.touseandtheirorderEditField.Value = '1,2,3,4';
            app.HelpSubtractBG = uibutton(app.MeasurefluorescentsignalandclassifycellsPanel, 'push');
            app.HelpSubtractBG.ButtonPushedFcn = createCallbackFcn(app, @HelpSubtractBGButtonPushed, true);
            app.HelpSubtractBG.Text = '?';
            app.HelpFlChannelsToUse = uibutton(app.MeasurefluorescentsignalandclassifycellsPanel, 'push');
            app.HelpFlChannelsToUse.ButtonPushedFcn = createCallbackFcn(app, @HelpFlChannelsToUseButtonPushed, true);
            app.HelpFlChannelsToUse.Text = '?';
            app.HelpNumClusters = uibutton(app.MeasurefluorescentsignalandclassifycellsPanel, 'push');
            app.HelpNumClusters.ButtonPushedFcn = createCallbackFcn(app, @HelpNumClustersButtonPushed, true);
            app.HelpNumClusters.Text = '?';
            app.ofclustersEditFieldLabel = uilabel(app.MeasurefluorescentsignalandclassifycellsPanel);
            app.ofclustersEditFieldLabel.Text = '# of clusters';
            app.ofclustersEditField = uieditfield(app.MeasurefluorescentsignalandclassifycellsPanel, 'numeric');
            app.ofclustersEditField.Value = 15;
            app.HelpFlChannelsToUse_2 = uibutton(app.MeasurefluorescentsignalandclassifycellsPanel, 'push');
            app.HelpFlChannelsToUse_2.Text = '?';
            app.NavigatoritemsimportPanel = uipanel(app.SettingsTab);
            app.NavigatoritemsimportPanel.Title = '12. Navigator items import';
            app.TransformDropDown_2Label = uilabel(app.NavigatoritemsimportPanel);
            app.TransformDropDown_2Label.Text = 'Transform';
            app.XYtoStageTransformDropDown = uidropdown(app.NavigatoritemsimportPanel);
            app.XYtoStageTransformDropDown.Items = {'affine', 'similarity', 'projective'};
            app.XYtoStageTransformDropDown.Value = 'affine';
            app.ButtonHelpXYStageTform = uibutton(app.NavigatoritemsimportPanel, 'push');
            app.ButtonHelpXYStageTform.ButtonPushedFcn = createCallbackFcn(app, @ButtonHelpXYStageTformPushed, true);
            app.ButtonHelpXYStageTform.Text = '?';
            app.CCscaledownEditFieldLabel = uilabel(app.NavigatoritemsimportPanel);
            app.CCscaledownEditFieldLabel.Text = 'CC scaledown';
            app.CCscaledownEditField = uieditfield(app.NavigatoritemsimportPanel, 'numeric');
            app.CCscaledownEditField.Value = 0.1;
            app.ButtonHelpCCscaledown = uibutton(app.NavigatoritemsimportPanel, 'push');
            app.ButtonHelpCCscaledown.ButtonPushedFcn = createCallbackFcn(app, @ButtonHelpCCscaledownPushed, true);
            app.ButtonHelpCCscaledown.Text = '?';
            app.AddAcquireCheckBox = uicheckbox(app.NavigatoritemsimportPanel);
            app.AddAcquireCheckBox.Text = 'Add ''Acquire''';
            app.AddAcquireCheckBox.Value = true;
            app.ImportmodeDropDownLabel = uilabel(app.NavigatoritemsimportPanel);
            app.ImportmodeDropDownLabel.Text = 'Import mode';
            app.ImportmodeDropDown = uidropdown(app.NavigatoritemsimportPanel);
            app.ImportmodeDropDown.Items = {'Transform', 'CoordsInMap', 'CoordsInPieces'};
            app.ImportmodeDropDown.Value = 'CoordsInMap';
            app.HelpImportMode = uibutton(app.NavigatoritemsimportPanel, 'push');
            app.HelpImportMode.ButtonPushedFcn = createCallbackFcn(app, @HelpImportModeButtonPushed, true);
            app.HelpImportMode.Text = '?';
            app.HelpAddAcquire = uibutton(app.NavigatoritemsimportPanel, 'push');
            app.HelpAddAcquire.ButtonPushedFcn = createCallbackFcn(app, @HelpAddAcquireButtonPushed, true);
            app.HelpAddAcquire.Text = '?';
            app.IncludedCheckBox = uicheckbox(app.NavigatoritemsimportPanel);
            app.IncludedCheckBox.Text = 'Included';
            app.IncludedCheckBox.Value = true;
            app.ApprovedCheckBox = uicheckbox(app.NavigatoritemsimportPanel);
            app.ApprovedCheckBox.Text = 'Approved';
            app.ApprovedCheckBox.Value = true;
            app.HelpDBtable = uibutton(app.NavigatoritemsimportPanel, 'push');
            app.HelpDBtable.ButtonPushedFcn = createCallbackFcn(app, @HelpDBtableButtonPushed, true);
            app.HelpDBtable.Text = '?';
            app.HelpTextAreaLabel = uilabel(app.SettingsTab);
            app.HelpTextAreaLabel.Text = 'Help';
            app.HelpTextArea = uitextarea(app.SettingsTab);
            app.BrowsingPanel = uipanel(app.SettingsTab);
            app.BrowsingPanel.Title = '8-10. Browsing';
            app.PanelsizeEditFieldLabel = uilabel(app.BrowsingPanel);
            app.PanelsizeEditFieldLabel.Text = 'Panel size';
            app.PanelsizeEditField = uieditfield(app.BrowsingPanel, 'numeric');
            app.PanelsizeEditField.Value = 100;
            app.NrowsEditFieldLabel = uilabel(app.BrowsingPanel);
            app.NrowsEditFieldLabel.Text = 'N rows';
            app.NrowsEditField = uieditfield(app.BrowsingPanel, 'numeric');
            app.NrowsEditField.Limits = [1 12];
            app.NrowsEditField.Value = 12;
            app.HelpPanelSize = uibutton(app.BrowsingPanel, 'push');
            app.HelpPanelSize.ButtonPushedFcn = createCallbackFcn(app, @HelpPanelSizeButtonPushed, true);
            app.HelpPanelSize.Text = '?';
            app.HelpNrows = uibutton(app.BrowsingPanel, 'push');
            app.HelpNrows.ButtonPushedFcn = createCallbackFcn(app, @HelpNrowsButtonPushed, true);
            app.HelpNrows.Text = '?';
            app.autoloadCheckBox = uicheckbox(app.SettingsTab);
            app.autoloadCheckBox.ValueChangedFcn = createCallbackFcn(app, @autoloadCheckBoxValueChanged, true);
            app.autoloadCheckBox.Text = 'auto load';
            app.savesetingsButton = uibutton(app.SettingsTab, 'push');
            app.savesetingsButton.ButtonPushedFcn = createCallbackFcn(app, @savesetingsButtonPushed, true);
            app.savesetingsButton.Text = 'save setings';
            app.loadsetings = uibutton(app.SettingsTab, 'push');
            app.loadsetings.ButtonPushedFcn = createCallbackFcn(app, @loadsetingsButtonPushed, true);
            app.loadsetings.Text = 'load setings';
            app.ExportcellsPanel = uipanel(app.SettingsTab);
            app.ExportcellsPanel.Title = '11. Export cells';
            app.CropsizemEditFieldLabel = uilabel(app.ExportcellsPanel);
            app.CropsizemEditFieldLabel.Text = 'Crop size (µm)';
            app.CropsizemEditField = uieditfield(app.ExportcellsPanel, 'numeric');
            app.CropsizemEditField.Value = 6;
            app.HelpCropSize = uibutton(app.ExportcellsPanel, 'push');
            app.HelpCropSize.ButtonPushedFcn = createCallbackFcn(app, @HelpCropSizeButtonPushed, true);
            app.HelpCropSize.Text = '?';
            app.ResizingEditFieldLabel = uilabel(app.ExportcellsPanel);
            app.ResizingEditFieldLabel.Text = 'Resizing';
            app.ResizingEditField = uieditfield(app.ExportcellsPanel, 'numeric');
            app.ResizingEditField.Value = 1;
            app.AddLMchannelsEditFieldLabel = uilabel(app.ExportcellsPanel);
            app.AddLMchannelsEditFieldLabel.Text = 'Add LM channels:';
            app.AddLMchannelsEditField = uieditfield(app.ExportcellsPanel, 'text');
            app.AddLMchannelsEditField.Value = '1,2,3,4';
            app.HelpResizing = uibutton(app.ExportcellsPanel, 'push');
            app.HelpResizing.ButtonPushedFcn = createCallbackFcn(app, @HelpResizingButtonPushed, true);
            app.HelpResizing.Text = '?';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MultiCLEM_analyzer()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MultiCLEMAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MultiCLEMAnalyzerUIFigure)
        end
    end
end